classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Switch            matlab.ui.control.ToggleSwitch
        OUTOFBOUNDSLabel  matlab.ui.control.Label
        Lamp_z_change     matlab.ui.control.Lamp
        Lamp_y_change     matlab.ui.control.Lamp
        Lamp_x_change     matlab.ui.control.Lamp
        Lamp_singularity  matlab.ui.control.Lamp
        Joint_3Label      matlab.ui.control.Label
        Joint_2Label      matlab.ui.control.Label
        Joint_1Label      matlab.ui.control.Label
        joint_3_value     matlab.ui.control.EditField
        joint_2_value     matlab.ui.control.EditField
        joint_1_value     matlab.ui.control.EditField
        z_decrease        matlab.ui.control.Button
        z_increase        matlab.ui.control.Button
        y_decrease        matlab.ui.control.Button
        y_increase        matlab.ui.control.Button
        x_decrease        matlab.ui.control.Button
        z_value           matlab.ui.control.NumericEditField
        y_value           matlab.ui.control.NumericEditField
        x_value           matlab.ui.control.NumericEditField
        x_increase        matlab.ui.control.Button
    end

    
    properties (Access = private)
        x = 2;
        y = 0;
        z = 0;
        link_length_1 = 0;
        link_length_2 = 1;
        link_length_3 = 1;
        th_1_initial = 0;
        th_1_final = 0;
        th_2_initial = 0;
        th_2_final = 0;
        th_3_initial = 0;
        th_3_final = 0;
        time = 0.5;
        time_interval = 0.01;
        dh_param = [90 0 0;0 0 1;0 0 1];   
        count = 0;
        side = 1;
    end
    
    methods (Access = private)
        
        function joint_angle_cal(app)
            
            r1 = sqrt((app.x)^2 + (app.y)^2);
            r = sqrt((app.z)^2 + r1^2);
            a = ((app.link_length_2)^2 + r^2 - (app.link_length_3)^2)/(2*r*app.link_length_2);
            b = ((app.link_length_2)^2 + (app.link_length_3)^2 - r^2)/(2*app.link_length_3*app.link_length_2);
            
            app.th_1_final = atan2d(app.y,app.x);     
            if app.side == 1                   
                app.th_2_final = atan2d(app.z,r1) + acosd(a);            
                app.th_3_final = -(180 - acosd(b));
            elseif app.side == -1
                app.th_2_final = atan2d(app.z,r1) - acosd(a);            
                app.th_3_final = 180 - acosd(b);            
            end
            singularity(app, a, b);
        end
        
        function path_cal(app)
            v1 = (app.th_1_final - app.th_1_initial)/app.time;
            v2 = (app.th_2_final - app.th_2_initial)/app.time;
            v3 = (app.th_3_final - app.th_3_initial)/app.time;

            i = 0:0.01:app.time;
            th_1_path = app.th_1_initial + v1*i;
            th_2_path = app.th_2_initial + v2*i;
            th_3_path = app.th_3_initial + v3*i;

            app.th_1_initial = app.th_1_final;
            app.th_2_initial = app.th_2_final;
            app.th_3_initial = app.th_3_final;
            
            cordinate_cal(app, th_1_path, th_2_path, th_3_path);
        end
        
        function cordinate_cal(app, th_1_path, th_2_path, th_3_path)
            i = 0:app.time_interval:app.time;
            x0(round(i*(1/app.time_interval) + 1)) = 0;
            y0(round(i*(1/app.time_interval) + 1)) = 0;
            z0(round(i*(1/app.time_interval) + 1)) = 0;

            x1(round(i*(1/app.time_interval) + 1)) = 0;
            y1(round(i*(1/app.time_interval) + 1)) = 0;
            z1(round(i*(1/app.time_interval) + 1)) = app.dh_param(1,3);
            
            x2 = app.dh_param(2,3)*(cosd(th_1_path).*cosd(th_2_path));
            y2 = app.dh_param(2,3)*(sind(th_1_path).*cosd(th_2_path));
            z2 = app.dh_param(2,3)*sind(th_2_path);
            
            for i = 0:app.time_interval:app.time
                h_3_0 = transformation_matrice_cal(app, th_1_path(round(i*(1/app.time_interval) + 1)), th_2_path(round(i*(1/app.time_interval) + 1)), th_3_path(round(i*(1/app.time_interval) + 1)));
                x3(round(i*(1/app.time_interval) + 1)) = h_3_0(1,4);
                y3(round(i*(1/app.time_interval) + 1)) = h_3_0(2,4);
                z3(round(i*(1/app.time_interval) + 1)) = h_3_0(3,4);
            end
            
            mapping(app, x0,x1,x2,x3,y0,y1,y2,y3,z0,z1,z2,z3);
        end
        
        function mapping(app, x0,x1,x2,x3,y0,y1,y2,y3,z0,z1,z2,z3)
            i = 0:(app.time_interval):app.time;
            x_1_2 = [x1(round(i*(1/app.time_interval) + 1)); x2(round(i*(1/app.time_interval) + 1))];
            y_1_2 = [y1(round(i*(1/app.time_interval) + 1)); y2(round(i*(1/app.time_interval) + 1))];
            z_1_2 = [z1(round(i*(1/app.time_interval) + 1)); z2(round(i*(1/app.time_interval) + 1))];
            x_2_3 = [x2(round(i*(1/app.time_interval) + 1)); x3(round(i*(1/app.time_interval) + 1))];
            y_2_3 = [y2(round(i*(1/app.time_interval) + 1)); y3(round(i*(1/app.time_interval) + 1))];
            z_2_3 = [z2(round(i*(1/app.time_interval) + 1)); z3(round(i*(1/app.time_interval) + 1))];

            for i = 0:(app.time_interval):app.time    
    
                p = plot3([x_1_2(1,round(i*100 + 1)),x_1_2(2,round(i*100 + 1))],[y_1_2(1,round(i*100 + 1)),y_1_2(2,round(i*100 + 1))],...
                    [z_1_2(1,round(i*100 + 1)),z_1_2(2,round(i*100 + 1))],'b',[x_2_3(1,round(i*100 + 1)),x_2_3(2,round(i*100 + 1))],...
                    [y_2_3(1,round(i*100 + 1)),y_2_3(2,round(i*100 + 1))],[z_2_3(1,round(i*100 + 1)),z_2_3(2,round(i*100 + 1))],'g',...
                    [-3,3],[0,0],[0,0],'k',[0,0],[-3,3],[0,0],'k',[0,0],[0,0],[-3,3],'k');
                
                p(1).LineWidth = 2;
                p(2).LineWidth = 2;
                p(1).Marker = 'o';
                p(2).Marker = 'o';
                p(1).MarkerFaceColor = 'k';
                p(2).MarkerFaceColor = 'k';
                p(1).MarkerEdgeColor = 'k';
                p(2).MarkerEdgeColor = 'k'; 
                xlim([-3, 3]);
                ylim([-3, 3]);
                zlim([-3, 3]);
                grid on
                xlabel('X');
                ylabel('Y');
                zlabel('Z');
                
                pause((app.time_interval))
            end
            app.joint_1_value.Value = string(app.th_1_final);
            app.joint_2_value.Value = string(app.th_2_final);
            app.joint_3_value.Value = string(app.th_3_final);
        end
        
        function h_3_0 = transformation_matrice_cal(app, th_1, th_2, th_3)
            alp_1 = app.dh_param(1,1);
            alp_2 = app.dh_param(2,1);
            alp_3 = app.dh_param(3,1);
            d1 = app.dh_param(1,2);
            d2 = app.dh_param(2,2);
            d3 = app.dh_param(3,2);
            a1 = app.dh_param(1,3);
            a2 = app.dh_param(2,3);
            a3 = app.dh_param(3,3);

            h_1_0 = [cosd(th_1) -sind(th_1)*cosd(alp_1) sind(th_1)*sind(alp_1) a1*cosd(th_1); ...
                sind(th_1) cosd(th_1)*cosd(alp_1) -cosd(th_1)*sind(alp_1) a1*sind(th_1); ...
                0 sind(alp_1) cosd(alp_1) d1; ...
                0 0 0 1];
            h_2_1 = [cosd(th_2) -sind(th_2)*cosd(alp_2) sind(th_2)*sind(alp_2) a2*cosd(th_2); ...
                sind(th_2) cosd(th_2)*cosd(alp_2) -cosd(th_2)*sind(alp_2) a2*sind(th_2); ...
                0 sind(alp_2) cosd(alp_2) d2; ...
                0 0 0 1];
            h_3_2 = [cosd(th_3) -sind(th_3)*cosd(alp_3) sind(th_3)*sind(alp_3) a3*cosd(th_3); ...
                sind(th_3) cosd(th_3)*cosd(alp_3) -cosd(th_3)*sind(alp_3) a3*sind(th_3); ...
                0 sind(alp_3) cosd(alp_3) d3; ...
                0 0 0 1];
            h_3_0 = (h_1_0 * h_2_1) * (h_3_2);
        end
        
        function lamp_on_off(app)
            if app.count == 1 || app.count == 2
                app.Lamp_x_change.Color = 'green';
                app.Lamp_y_change.Color = 'red';
                app.Lamp_z_change.Color = 'red';
            elseif app.count == 3 || app.count == 4
                app.Lamp_x_change.Color = 'red';
                app.Lamp_y_change.Color = 'green';
                app.Lamp_z_change.Color = 'red';
            elseif app.count == 5 || app.count == 6
                app.Lamp_x_change.Color = 'red';
                app.Lamp_y_change.Color = 'red';
                app.Lamp_z_change.Color = 'green';
            else
                app.Lamp_x_change.Color = 'red';
                app.Lamp_y_change.Color = 'red';
                app.Lamp_z_change.Color = 'red';
            end
        
        end
        
        function singularity(app, a, b)
            if a < -1 || a > 1 || b < -1 || b > 1
                app.Lamp_singularity.Color = 'red';     
                if app.count == 1
                    app.x = app.x - 0.1;
                    app.x_value.Value = app.x;
                end
                if app.count == 2
                    app.x = app.x + 0.1;
                    app.x_value.Value = app.x;
                end
                if app.count == 3
                    app.y = app.y - 0.1;
                    app.y_value.Value = app.y;
                end
                if app.count == 4
                    app.y = app.y + 0.1;
                    app.y_value.Value = app.y;
                end
                if app.count == 5
                    app.z = app.z - 0.1;
                    app.z_value.Value = app.z;
                end
                if app.count == 6
                    app.z = app.z + 0.1;
                    app.z_value.Value = app.z;
                end
            else
                app.Lamp_singularity.Color = 'green';
                app.x_value.Value = app.x;
                app.y_value.Value = app.y;
                app.z_value.Value = app.z;
                lamp_on_off(app);
                path_cal(app);
            end
        end
        
        function change_config(app)
            r1 = sqrt((app.x)^2 + (app.y)^2);
            r = sqrt((app.z)^2 + r1^2);
            a = ((app.link_length_2)^2 + r^2 - (app.link_length_3)^2)/(2*r*app.link_length_2);
            
            if app.side == -1
                app.th_2_final = -(acosd(a) - atan2d(app.z,r1));
                app.th_3_final = -(app.th_3_final);
            elseif app.side == 1
                app.th_2_final = (acosd(a) + atan2d(app.z,r1));
                app.th_3_final = -(app.th_3_final);
            end
            path_cal(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: x_increase
        function x_increaseButtonPushed(app, event)
            app.x = app.x + 0.1;
            app.count = 1;
            joint_angle_cal(app);
            app.count = 0;
            lamp_on_off(app);
        end

        % Button pushed function: x_decrease
        function x_decreaseButtonPushed(app, event)
            app.x = app.x - 0.1;            
            app.count = 2;
            
            joint_angle_cal(app);
            app.count = 0;
            lamp_on_off(app);
        end

        % Button pushed function: y_increase
        function y_increaseButtonPushed(app, event)
            app.y = app.y + 0.1;            
            app.count = 3;            
            joint_angle_cal(app);
            app.count = 0;
            lamp_on_off(app);
        end

        % Button pushed function: y_decrease
        function y_decreaseButtonPushed(app, event)
            app.y = app.y - 0.1;            
            app.count = 4;            
            joint_angle_cal(app);
            app.count = 0;
            lamp_on_off(app);
        end

        % Button pushed function: z_increase
        function z_increaseButtonPushed(app, event)
            app.z = app.z + 0.1;            
            app.count = 5;            
            joint_angle_cal(app)
            app.count = 0;
            lamp_on_off(app);
        end

        % Button pushed function: z_decrease
        function z_decreaseButtonPushed(app, event)
            app.z = app.z - 0.1;            
            app.count = 6;            
            joint_angle_cal(app)
            app.count = 0;
            lamp_on_off(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'elbow_up') == 1
                app.side = 1;
            else
                app.side = -1;
            end
            change_config(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.x_increase = uibutton(app.UIFigure, 'push');
            app.x_increase.ButtonPushedFcn = createCallbackFcn(app, @x_increaseButtonPushed, true);
            app.x_increase.Text = 'X+';
            app.x_value = uieditfield(app.UIFigure, 'numeric');
            app.y_value = uieditfield(app.UIFigure, 'numeric');
            app.z_value = uieditfield(app.UIFigure, 'numeric');
            app.x_decrease = uibutton(app.UIFigure, 'push');
            app.x_decrease.ButtonPushedFcn = createCallbackFcn(app, @x_decreaseButtonPushed, true);
            app.x_decrease.Text = 'X-';
            app.y_increase = uibutton(app.UIFigure, 'push');
            app.y_increase.ButtonPushedFcn = createCallbackFcn(app, @y_increaseButtonPushed, true);
            app.y_increase.Text = 'Y+';
            app.y_decrease = uibutton(app.UIFigure, 'push');
            app.y_decrease.ButtonPushedFcn = createCallbackFcn(app, @y_decreaseButtonPushed, true);
            app.y_decrease.Text = 'Y-';
            app.z_increase = uibutton(app.UIFigure, 'push');
            app.z_increase.ButtonPushedFcn = createCallbackFcn(app, @z_increaseButtonPushed, true);
            app.z_increase.Text = 'Z+';
            app.z_decrease = uibutton(app.UIFigure, 'push');
            app.z_decrease.ButtonPushedFcn = createCallbackFcn(app, @z_decreaseButtonPushed, true);
            app.z_decrease.Text = 'Z-';
            app.joint_1_value = uieditfield(app.UIFigure, 'text');
            app.joint_2_value = uieditfield(app.UIFigure, 'text');
            app.joint_3_value = uieditfield(app.UIFigure, 'text');
            app.Joint_1Label = uilabel(app.UIFigure);
            app.Joint_1Label.Text = 'Joint_1';
            app.Joint_2Label = uilabel(app.UIFigure);
            app.Joint_2Label.Text = 'Joint_2';
            app.Joint_3Label = uilabel(app.UIFigure);
            app.Joint_3Label.Text = 'Joint_3';
            app.Lamp_singularity = uilamp(app.UIFigure);
            app.Lamp_x_change = uilamp(app.UIFigure);
            app.Lamp_y_change = uilamp(app.UIFigure);
            app.Lamp_z_change = uilamp(app.UIFigure);
            app.OUTOFBOUNDSLabel = uilabel(app.UIFigure);
            app.OUTOFBOUNDSLabel.Text = 'OUT OF BOUNDS!';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'elbow_down', 'elbow_up'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'elbow_up';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end